classdef dip_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        tEditField                   matlab.ui.control.NumericEditField
        tEditFieldLabel              matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_2Label             matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        w3EditField                  matlab.ui.control.NumericEditField
        w3EditFieldLabel             matlab.ui.control.Label
        CheckBox_upr_greater_than_0  matlab.ui.control.CheckBox
        CheckBox_upr_value           matlab.ui.control.CheckBox
        CheckBox_upr_time            matlab.ui.control.CheckBox
        LEditField                   matlab.ui.control.NumericEditField
        LEditFieldLabel              matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        TEditField                   matlab.ui.control.NumericEditField
        TEditFieldLabel              matlab.ui.control.Label
        w2EditFieldLabel             matlab.ui.control.Label
        w2EditField                  matlab.ui.control.NumericEditField
        w1EditFieldLabel             matlab.ui.control.Label
        w1EditField                  matlab.ui.control.NumericEditField
        Button                       matlab.ui.control.Button
        ButtonGroup_start            matlab.ui.container.ButtonGroup
        Button_start_lock            matlab.ui.control.RadioButton
        Button_start_unlock          matlab.ui.control.RadioButton
        ButtonGroup_upr              matlab.ui.container.ButtonGroup
        Button_model_upr_off         matlab.ui.control.RadioButton
        Button_model_upr_on          matlab.ui.control.RadioButton
        dEditField                   matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        ButtonGroup_model            matlab.ui.container.ButtonGroup
        Button_model_1               matlab.ui.control.RadioButton
        Button_model_2               matlab.ui.control.RadioButton
        sEditField                   matlab.ui.control.NumericEditField
        sEditFieldLabel              matlab.ui.control.Label
        lambdaEditField              matlab.ui.control.NumericEditField
        lambdaEditFieldLabel         matlab.ui.control.Label
        rEditField                   matlab.ui.control.NumericEditField
        rEditFieldLabel              matlab.ui.control.Label
        qEditField                   matlab.ui.control.NumericEditField
        qEditFieldLabel              matlab.ui.control.Label
        kEditField                   matlab.ui.control.NumericEditField
        kEditFieldLabel              matlab.ui.control.Label
        jEditField                   matlab.ui.control.NumericEditField
        jEditFieldLabel              matlab.ui.control.Label
        mEditField                   matlab.ui.control.NumericEditField
        mEditFieldLabel              matlab.ui.control.Label
        pEditField                   matlab.ui.control.NumericEditField
        pEditFieldLabel              matlab.ui.control.Label
        siEditField                  matlab.ui.control.NumericEditField
        siEditFieldLabel             matlab.ui.control.Label
        hEditField                   matlab.ui.control.NumericEditField
        hEditFieldLabel              matlab.ui.control.Label
        gEditFieldLabel              matlab.ui.control.Label
        gEditField                   matlab.ui.control.NumericEditField
        fEditField                   matlab.ui.control.NumericEditField
        fEditFieldLabel              matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup_model
        function ButtonGroup_modelSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_model.SelectedObject;
            if selectedButton == app.Button_model_1
                app.aEditFieldLabel.Text = 'a';
                app.aEditField.Value = 0.514;
                app.bEditFieldLabel.Text = 'b';
                app.bEditField.Value = 1.02e-09;
                app.cEditFieldLabel.Text = 'c';
                app.cEditField.Value = 3.32e-07;
                app.fEditFieldLabel.Text = 'f';
                app.fEditField.Value = 0.0412;
                app.gEditFieldLabel.Text = 'g';
                app.gEditField.Value = 0.025;
                app.hEditFieldLabel.Text = 'h';
                app.hEditField.Value = 20200000;
                app.siEditFieldLabel.Text = 'si';
                app.siEditField.Value = 13000;
                app.pEditFieldLabel.Text = 'p';
                app.pEditField.Value = 1e-07;
                app.mEditFieldLabel.Text = 'm';
                app.mEditField.Value = 0.02;
                set(app.jEditFieldLabel ,'Visible','On')
                set(app.jEditField ,'Visible','On')
                app.jEditFieldLabel.Text = 'j';
                app.jEditField.Value = 0.0375;
                set(app.kEditFieldLabel ,'Visible','On')
                set(app.kEditField ,'Visible','On')
                app.kEditFieldLabel.Text = 'k';
                app.kEditField.Value = 20200000;
                app.qEditFieldLabel.Text = 'q';
                set(app.qEditFieldLabel ,'Visible','On')
                set(app.qEditField ,'Visible','On')
                app.qEditField.Value = 3.42e-10;
                set(app.rEditFieldLabel ,'Visible','On')
                set(app.rEditField ,'Visible','On')
                app.rEditFieldLabel.Text = 'r';
                app.rEditField.Value = 1.1e-07;
                set(app.dEditFieldLabel ,'Visible','On')
                set(app.dEditField ,'Visible','On')
                app.dEditFieldLabel.Text = 'd';
                app.dEditField.Value = 1.143;
                set(app.lambdaEditFieldLabel ,'Visible','On')
                set(app.lambdaEditField ,'Visible','On')
                app.lambdaEditFieldLabel.Text = 'lambda';
                app.lambdaEditField.Value = 0.58;
                set(app.sEditFieldLabel ,'Visible','On')
                set(app.sEditField ,'Visible','On')
                app.sEditFieldLabel.Text = 's';
                app.sEditField.Value = 2.73;
                if app.ButtonGroup_start.SelectedObject == app.Button_start_unlock
                    app.NEditFieldLabel.Text = 'T';
                    app.TEditField.Value = 1e3;
                    app.NEditFieldLabel.Text = 'N';
                    app.NEditField.Value = 1.5e5;
                    app.LEditFieldLabel.Text = 'L';
                    app.LEditField.Value = 1;
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        app.tEditField.Value = 80;
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        app.tEditField.Value = 30;
                    end
                end
                if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                    app.w1EditField.Value = 1; 
                    app.w2EditField.Value = 1+13321/36679;
                    set(app.w3EditField ,'Visible','Off')
                    set(app.w3EditFieldLabel ,'Visible','Off')
                    app.CheckBox_upr_time.Layout.Row=app.w2EditField.Layout.Row+1;               
                    if app.CheckBox_upr_time.Value == 1
                        app.EditField.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                        app.Label.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                        app.CheckBox_upr_value.Layout.Row=app.EditField.Layout.Row+1;
                        if app.CheckBox_upr_value.Value == 1
                            app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                        else
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                        end
                    else
                        app.CheckBox_upr_value.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                        if app.CheckBox_upr_value.Value == 1
                            app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                        else
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                        end
                    end
                end
            end
            if selectedButton == app.Button_model_2
                app.aEditFieldLabel.Text = 'a1';
                app.aEditField.Value = 1.82;
                app.bEditFieldLabel.Text = 'a2';
                app.bEditField.Value = 0.239;
                app.cEditFieldLabel.Text = 'a3';
                app.cEditField.Value = 0.2;
                app.siEditFieldLabel.Text = 'a4';
                app.siEditField.Value = 0.04;
                app.fEditFieldLabel.Text = 'a5';
                app.fEditField.Value = 0.0691;
                app.gEditFieldLabel.Text = 'a6';
                app.gEditField.Value = 0.5;
                app.hEditFieldLabel.Text = 'a7';
                app.hEditField.Value = 0.01;
                app.pEditFieldLabel.Text = 'a8';
                app.pEditField.Value = 2;
                app.mEditFieldLabel.Text = 'K';
                app.mEditField.Value = 1;
                set(app.jEditFieldLabel ,'Visible','Off')
                set(app.jEditField ,'Visible','Off')
                set(app.kEditFieldLabel ,'Visible','Off')
                set(app.kEditField ,'Visible','Off')
                set(app.qEditFieldLabel ,'Visible','Off')
                set(app.qEditField ,'Visible','Off')
                set(app.rEditFieldLabel ,'Visible','Off')
                set(app.rEditField ,'Visible','Off')
                set(app.dEditFieldLabel ,'Visible','Off')
                set(app.dEditField ,'Visible','Off')
                set(app.lambdaEditFieldLabel ,'Visible','Off')
                set(app.lambdaEditField ,'Visible','Off')
                set(app.sEditFieldLabel ,'Visible','Off')
                set(app.sEditField ,'Visible','Off')
                if app.ButtonGroup_start.SelectedObject == app.Button_start_unlock
                    app.NEditFieldLabel.Text = 'T';
                    app.TEditField.Value = 1;
                    app.NEditFieldLabel.Text = 'H';
                    app.NEditField.Value = 0.1;
                    app.LEditFieldLabel.Text = 'R';
                    app.LEditField.Value = 0.85;
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        app.tEditField.Value = 500;
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        app.tEditField.Value = 100;
                    end
                end
                if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                    app.w1EditField.Value = 1; 
                    app.w2EditField.Value = 10;
                    app.w3EditField.Value = 100;
                    set(app.w3EditField ,'Visible','On')
                    set(app.w3EditFieldLabel ,'Visible','On')
                    app.w3EditField.Layout.Row=app.w2EditField.Layout.Row+1;
                    app.CheckBox_upr_time.Layout.Row=app.w3EditField.Layout.Row+1;
                    if app.CheckBox_upr_time.Value == 1
                        app.EditField.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                        app.Label.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                        app.CheckBox_upr_value.Layout.Row=app.EditField.Layout.Row+1; 
                        if app.CheckBox_upr_value.Value == 1
                            app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                        else
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                        end
                    else
                        app.CheckBox_upr_value.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                        if app.CheckBox_upr_value.Value == 1
                            app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                        else
                            app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                        end
                    end
                end
            end
        end

        % Selection changed function: ButtonGroup_start
        function ButtonGroup_startSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_start.SelectedObject;
            if selectedButton == app.Button_start_lock
                set(app.TEditField ,'Visible','Off')
                set(app.TEditFieldLabel ,'Visible','Off')
                set(app.NEditField ,'Visible','Off')
                set(app.NEditFieldLabel ,'Visible','Off')
                set(app.LEditField ,'Visible','Off')
                set(app.LEditFieldLabel ,'Visible','Off')
                set(app.tEditField ,'Visible','Off')
                set(app.tEditFieldLabel ,'Visible','Off')
                app.Button.Layout.Row = 2;
                app.Button.Layout.Column = [3 4];
            end
            if selectedButton == app.Button_start_unlock
                set(app.TEditField ,'Visible','On')
                set(app.TEditFieldLabel ,'Visible','On')
                set(app.NEditField ,'Visible','On')
                set(app.NEditFieldLabel ,'Visible','On')
                set(app.LEditField ,'Visible','On')
                set(app.LEditFieldLabel ,'Visible','On')
                set(app.tEditField ,'Visible','On')
                set(app.tEditFieldLabel ,'Visible','On')
                app.Button.Layout.Row = app.tEditFieldLabel.Layout.Row+1;
                app.Button.Layout.Column = [3 4];
                if app.ButtonGroup_model.SelectedObject == app.Button_model_1
                    app.NEditFieldLabel.Text = 'T';
                    app.TEditField.Value = 1e3;
                    app.NEditFieldLabel.Text = 'N';
                    app.NEditField.Value = 1.5e5;
                    app.LEditFieldLabel.Text = 'L';
                    app.LEditField.Value = 1;
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        app.tEditField.Value = 80;
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        app.tEditField.Value = 30;
                    end
                end
                if app.ButtonGroup_model.SelectedObject == app.Button_model_2
                    app.NEditFieldLabel.Text = 'T';
                    app.TEditField.Value = 1;
                    app.NEditFieldLabel.Text = 'H';
                    app.NEditField.Value = 0.1;
                    app.LEditFieldLabel.Text = 'R';
                    app.LEditField.Value = 0.85;
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        app.tEditField.Value = 500;
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        app.tEditField.Value = 100;
                    end
                end
            end
        end

        % Selection changed function: ButtonGroup_upr
        function ButtonGroup_uprSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_upr.SelectedObject;
            if selectedButton == app.Button_model_upr_off
                set(app.w1EditField ,'Visible','Off')
                set(app.w1EditFieldLabel ,'Visible','Off')
                set(app.w2EditField ,'Visible','Off')
                set(app.w2EditFieldLabel ,'Visible','Off')
                set(app.w3EditField ,'Visible','Off')
                set(app.w3EditFieldLabel ,'Visible','Off')
                set(app.CheckBox_upr_time ,'Visible','Off')
                set(app.CheckBox_upr_value ,'Visible','Off')
                set(app.EditField ,'Visible','Off')
                app.EditField.Value=-1;
                set(app.Label ,'Visible','Off')
                set(app.EditField_2 ,'Visible','Off')
                app.EditField_2.Value=-1;
                set(app.EditField_2Label ,'Visible','Off')
                set(app.CheckBox_upr_greater_than_0 ,'Visible','Off')
                if app.ButtonGroup_model.SelectedObject == app.Button_model_1
                    app.tEditField.Value = 80;
                end
                if app.ButtonGroup_model.SelectedObject == app.Button_model_2
                    app.tEditField.Value = 500;
                end
            end
            if selectedButton == app.Button_model_upr_on
                set(app.w1EditField ,'Visible','On')
                set(app.w1EditFieldLabel ,'Visible','On')
                set(app.w2EditField ,'Visible','On')
                set(app.w2EditFieldLabel ,'Visible','On')
                if app.ButtonGroup_model.SelectedObject == app.Button_model_1
                    app.w1EditField.Value = 1; 
                    app.w2EditField.Value = 1+13321/36679; 
                    set(app.w3EditField ,'Visible','Off')
                    set(app.w3EditFieldLabel ,'Visible','Off')
                    app.CheckBox_upr_time.Layout.Row=app.w2EditField.Layout.Row+1;
                    app.tEditField.Value = 30;      
                end
                if app.ButtonGroup_model.SelectedObject == app.Button_model_2
                    app.w1EditField.Value = 1; 
                    app.w2EditField.Value = 10;
                    app.w3EditField.Value = 100;
                    set(app.w3EditField ,'Visible','On')
                    set(app.w3EditFieldLabel ,'Visible','On')
                    app.CheckBox_upr_time.Layout.Row=app.w3EditField.Layout.Row+1;
                    app.tEditField.Value = 100;
                end
                app.CheckBox_upr_value.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                set(app.CheckBox_upr_time ,'Visible','On')
                app.CheckBox_upr_time.Value=0;
                set(app.CheckBox_upr_value ,'Visible','On')
                app.CheckBox_upr_value.Value=0;
                set(app.CheckBox_upr_greater_than_0 ,'Visible','On')
                app.CheckBox_upr_greater_than_0.Value=1;
                set(app.EditField ,'Visible','Off')
                app.EditField.Value=-1;
                set(app.Label ,'Visible','Off')
                set(app.EditField_2 ,'Visible','Off')
                app.EditField_2.Value=-1;
                set(app.EditField_2Label ,'Visible','Off')
            end
        end

        % Value changed function: CheckBox_upr_time
        function CheckBox_upr_timeValueChanged(app, event)
            value = app.CheckBox_upr_time.Value;
            if value == 1
                app.EditField.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                app.Label.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                app.CheckBox_upr_value.Layout.Row=app.EditField.Layout.Row+1;
                app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                if app.CheckBox_upr_value.Value == 1
                    app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                else
                    app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                end
                if app.ButtonGroup_model.SelectedObject == app.Button_model_2
                    app.EditField.Value=-1;
                else
                    app.EditField.Value=0.2;
                end
                set(app.EditField ,'Visible','On')
                set(app.Label ,'Visible','On')
            else
                set(app.EditField ,'Visible','Off')
                set(app.Label ,'Visible','Off')
                app.EditField.Value=-1;
                app.CheckBox_upr_value.Layout.Row=app.CheckBox_upr_time.Layout.Row+1;
                app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                if app.CheckBox_upr_value.Value == 1
                    app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                else
                    app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
                end
            end
        end

        % Value changed function: CheckBox_upr_value
        function CheckBox_upr_valueValueChanged(app, event)
            value = app.CheckBox_upr_value.Value;
            app.EditField_2.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
            app.EditField_2Label.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
            if value == 1
                if app.ButtonGroup_model.SelectedObject == app.Button_model_2
                    app.EditField_2.Value=10;
                else
                    app.EditField_2.Value=1/4;
                end
                app.CheckBox_upr_greater_than_0.Layout.Row=app.EditField_2.Layout.Row+1;
                set(app.EditField_2 ,'Visible','On')
                set(app.EditField_2Label ,'Visible','On')
            else
                set(app.EditField_2 ,'Visible','Off')
                app.EditField_2.Value=-1;
                set(app.EditField_2Label ,'Visible','Off')
                app.CheckBox_upr_greater_than_0.Layout.Row=app.CheckBox_upr_value.Layout.Row+1;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            addpath(genpath(pwd))
            if app.ButtonGroup_model.SelectedObject == app.Button_model_1
                if app.ButtonGroup_start.SelectedObject == app.Button_start_lock
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        dip_fasa(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.siEditField.Value, ...
                                 app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, app.pEditField.Value, ...
                                 app.mEditField.Value, app.jEditField.Value, app.kEditField.Value, app.qEditField.Value, ...
                                 app.rEditField.Value, app.dEditField.Value, app.lambdaEditField.Value, app.sEditField.Value)
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        dip_fasa_with_upr(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.siEditField.Value, ...
                                          app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, app.pEditField.Value, ...
                                          app.mEditField.Value, app.jEditField.Value, app.kEditField.Value, app.qEditField.Value, ...
                                          app.rEditField.Value, app.dEditField.Value, app.lambdaEditField.Value, app.sEditField.Value, ...
                                          app.w1EditField.Value,app.w2EditField.Value, app.EditField.Value,  ...
                                          app.EditField_2.Value, app.CheckBox_upr_greater_than_0.Value)
                    end
                end
                if app.ButtonGroup_start.SelectedObject == app.Button_start_unlock
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        dip_model_nn(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.siEditField.Value, ...
                                     app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, app.pEditField.Value, ...
                                     app.mEditField.Value, app.jEditField.Value, app.kEditField.Value, app.qEditField.Value, ...
                                     app.rEditField.Value, app.dEditField.Value, app.lambdaEditField.Value, app.sEditField.Value, ...
                                     app.TEditField.Value, app.NEditField.Value, app.LEditField.Value, app.tEditField.Value)
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        dip_model_nn_with_upr(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, ...
                                              app.siEditField.Value, app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, ...
                                              app.pEditField.Value, app.mEditField.Value, app.jEditField.Value, app.kEditField.Value, ...
                                              app.qEditField.Value, app.rEditField.Value, app.dEditField.Value, app.lambdaEditField.Value, ...
                                              app.sEditField.Value, app.w1EditField.Value, app.w2EditField.Value, ...
                                              app.EditField.Value,  app.EditField_2.Value, app.CheckBox_upr_greater_than_0.Value, ...
                                              app.TEditField.Value, app.NEditField.Value, app.LEditField.Value, app.tEditField.Value)
                    end
                end
            end
            if app.ButtonGroup_model.SelectedObject == app.Button_model_2
                if app.ButtonGroup_start.SelectedObject == app.Button_start_lock
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        dip_fasa2(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.siEditField.Value, ...
                                  app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, app.pEditField.Value, ...
                                  app.mEditField.Value)
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        dip_fasa2_with_upr(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.siEditField.Value, ...
                                           app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, app.pEditField.Value, ...
                                           app.mEditField.Value, ...
                                           app.w1EditField.Value,app.w2EditField.Value, app.w3EditField.Value, app.EditField.Value, ...
                                           app.EditField_2.Value, app.CheckBox_upr_greater_than_0.Value)
                    end
                end
                if app.ButtonGroup_start.SelectedObject == app.Button_start_unlock
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_off
                        dip_model2(app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.siEditField.Value, ...
                                   app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, app.pEditField.Value, ...
                                   app.mEditField.Value, ...
                                   app.TEditField.Value, app.NEditField.Value, app.LEditField.Value, app.tEditField.Value)
                    end
                    if app.ButtonGroup_upr.SelectedObject == app.Button_model_upr_on
                        dip_model2_with_upr(app.aEditField.Value, app.bEditField.Value,  app.cEditField.Value, ...
                                            app.siEditField.Value, app.fEditField.Value, app.gEditField.Value, app.hEditField.Value, ...
                                            app.pEditField.Value, app.mEditField.Value, ...
                                            app.w1EditField.Value, app.w2EditField.Value, app.w3EditField.Value, ...
                                            app.EditField.Value,  app.EditField_2.Value, app.CheckBox_upr_greater_than_0.Value, ...
                                            app.TEditField.Value, app.NEditField.Value, app.LEditField.Value, app.tEditField.Value)
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Дипломная работа Краса Михаила';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.aEditFieldLabel = uilabel(app.GridLayout);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout, 'numeric');
            app.aEditField.Value = 0.514;
            app.bEditFieldLabel = uilabel(app.GridLayout);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout, 'numeric');
            app.bEditField.Value = 1.02e-09;
            app.cEditFieldLabel = uilabel(app.GridLayout);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.GridLayout, 'numeric');
            app.cEditField.Value = 3.32e-07;
            app.fEditFieldLabel = uilabel(app.GridLayout);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.GridLayout, 'numeric');
            app.fEditField.Value = 0.0412;
            app.gEditField = uieditfield(app.GridLayout, 'numeric');
            app.gEditField.Value = 0.025;
            app.gEditFieldLabel = uilabel(app.GridLayout);
            app.gEditFieldLabel.Text = 'g';
            app.hEditFieldLabel = uilabel(app.GridLayout);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.GridLayout, 'numeric');
            app.hEditField.Value = 20200000;
            app.siEditFieldLabel = uilabel(app.GridLayout);
            app.siEditFieldLabel.Text = 'si';
            app.siEditField = uieditfield(app.GridLayout, 'numeric');
            app.siEditField.Value = 13000;
            app.pEditFieldLabel = uilabel(app.GridLayout);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.GridLayout, 'numeric');
            app.pEditField.Value = 1e-07;
            app.mEditFieldLabel = uilabel(app.GridLayout);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridLayout, 'numeric');
            app.mEditField.Value = 0.02;
            app.jEditFieldLabel = uilabel(app.GridLayout);
            app.jEditFieldLabel.Text = 'j';
            app.jEditField = uieditfield(app.GridLayout, 'numeric');
            app.jEditField.Value = 0.0375;
            app.kEditFieldLabel = uilabel(app.GridLayout);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.GridLayout, 'numeric');
            app.kEditField.Value = 20200000;
            app.qEditFieldLabel = uilabel(app.GridLayout);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.GridLayout, 'numeric');
            app.qEditField.Value = 3.42e-10;
            app.rEditFieldLabel = uilabel(app.GridLayout);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.GridLayout, 'numeric');
            app.rEditField.Value = 1.1e-07;
            app.lambdaEditFieldLabel = uilabel(app.GridLayout);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.GridLayout, 'numeric');
            app.lambdaEditField.Value = 0.58;
            app.sEditFieldLabel = uilabel(app.GridLayout);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.GridLayout, 'numeric');
            app.sEditField.Value = 2.73;
            app.ButtonGroup_model = uibuttongroup(app.GridLayout);
            app.ButtonGroup_model.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_modelSelectionChanged, true);
            app.ButtonGroup_model.Title = 'Выбор модели';
            app.Button_model_2 = uiradiobutton(app.ButtonGroup_model);
            app.Button_model_2.Text = 'Модель 2';
            app.Button_model_1 = uiradiobutton(app.ButtonGroup_model);
            app.Button_model_1.Text = 'Модель 1';
            app.Button_model_1.Value = true;
            app.dEditFieldLabel = uilabel(app.GridLayout);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.GridLayout, 'numeric');
            app.dEditField.Value = 1.143;
            app.ButtonGroup_upr = uibuttongroup(app.GridLayout);
            app.ButtonGroup_upr.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_uprSelectionChanged, true);
            app.ButtonGroup_upr.Title = 'Переключение управления';
            app.Button_model_upr_on = uiradiobutton(app.ButtonGroup_upr);
            app.Button_model_upr_on.Text = 'С управлением';
            app.Button_model_upr_off = uiradiobutton(app.ButtonGroup_upr);
            app.Button_model_upr_off.Text = 'Без управления';
            app.Button_model_upr_off.Value = true;
            app.ButtonGroup_start = uibuttongroup(app.GridLayout);
            app.ButtonGroup_start.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_startSelectionChanged, true);
            app.ButtonGroup_start.Title = 'Начальные условия';
            app.Button_start_unlock = uiradiobutton(app.ButtonGroup_start);
            app.Button_start_unlock.Text = 'Произвольные';
            app.Button_start_lock = uiradiobutton(app.ButtonGroup_start);
            app.Button_start_lock.Text = 'Заданы';
            app.Button_start_lock.Value = true;
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Решить модель';
            app.w1EditField = uieditfield(app.GridLayout, 'numeric');
            app.w1EditField.Visible = 'off';
            app.w1EditField.Value = 1;
            app.w1EditFieldLabel = uilabel(app.GridLayout);
            app.w1EditFieldLabel.Visible = 'off';
            app.w1EditFieldLabel.Text = 'w1';
            app.w2EditField = uieditfield(app.GridLayout, 'numeric');
            app.w2EditField.Visible = 'off';
            app.w2EditField.Value = 1.36317784018103;
            app.w2EditFieldLabel = uilabel(app.GridLayout);
            app.w2EditFieldLabel.Visible = 'off';
            app.w2EditFieldLabel.Text = 'w2';
            app.TEditFieldLabel = uilabel(app.GridLayout);
            app.TEditFieldLabel.Visible = 'off';
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.GridLayout, 'numeric');
            app.TEditField.Visible = 'off';
            app.TEditField.Value = 1000;
            app.NEditFieldLabel = uilabel(app.GridLayout);
            app.NEditFieldLabel.Visible = 'off';
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.GridLayout, 'numeric');
            app.NEditField.Visible = 'off';
            app.NEditField.Value = 150000;
            app.LEditFieldLabel = uilabel(app.GridLayout);
            app.LEditFieldLabel.Visible = 'off';
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.GridLayout, 'numeric');
            app.LEditField.Visible = 'off';
            app.LEditField.Value = 1;
            app.CheckBox_upr_time = uicheckbox(app.GridLayout);
            app.CheckBox_upr_time.ValueChangedFcn = createCallbackFcn(app, @CheckBox_upr_timeValueChanged, true);
            app.CheckBox_upr_time.Visible = 'off';
            app.CheckBox_upr_time.Text = 'Ограничить управление по времени';
            app.CheckBox_upr_value = uicheckbox(app.GridLayout);
            app.CheckBox_upr_value.ValueChangedFcn = createCallbackFcn(app, @CheckBox_upr_valueValueChanged, true);
            app.CheckBox_upr_value.Visible = 'off';
            app.CheckBox_upr_value.Text = 'Ограничить управление по значению';
            app.CheckBox_upr_greater_than_0 = uicheckbox(app.GridLayout);
            app.CheckBox_upr_greater_than_0.Visible = 'off';
            app.CheckBox_upr_greater_than_0.Text = 'Управление всегда больше нуля';
            app.w3EditFieldLabel = uilabel(app.GridLayout);
            app.w3EditFieldLabel.Visible = 'off';
            app.w3EditFieldLabel.Text = 'w3';
            app.w3EditField = uieditfield(app.GridLayout, 'numeric');
            app.w3EditField.Visible = 'off';
            app.w3EditField.Value = 100;
            app.Label = uilabel(app.GridLayout);
            app.Label.Visible = 'off';
            app.Label.Text = 'Время';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Visible = 'off';
            app.EditField_2Label = uilabel(app.GridLayout);
            app.EditField_2Label.Visible = 'off';
            app.EditField_2Label.Text = {'Значение'; ''};
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.Visible = 'off';
            app.tEditFieldLabel = uilabel(app.GridLayout);
            app.tEditFieldLabel.Visible = 'off';
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.GridLayout, 'numeric');
            app.tEditField.Visible = 'off';
            app.tEditField.Value = 80;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dip_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end